classdef FinalGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OptionsMenu                    matlab.ui.container.Menu
        ResetMenu                      matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        CalculateMenu                  matlab.ui.container.Menu
        PSNRMenu                       matlab.ui.container.Menu
        MSEMenu                        matlab.ui.container.Menu
        SSIMMenu                       matlab.ui.container.Menu
        FacialRecognitionPanel         matlab.ui.container.Panel
        ComparisonLabel                matlab.ui.control.Label
        ExtractedFeaturesLabel         matlab.ui.control.Label
        OriginalImageLabel_3           matlab.ui.control.Label
        LoadPhotoforRecognitionButton  matlab.ui.control.Button
        FacialRecognitionOptionsPanel  matlab.ui.container.Panel
        MatchFeaturesDatabaseButton    matlab.ui.control.Button
        TakePhotoButton                matlab.ui.control.Button
        MatchFeaturesVideoButton       matlab.ui.control.Button
        MatchFeaturesImageButton       matlab.ui.control.Button
        EstimateFeaturesButton         matlab.ui.control.Button
        DetectFaceButton               matlab.ui.control.Button
        UIAxes_8                       matlab.ui.control.UIAxes
        UIAxes_7                       matlab.ui.control.UIAxes
        UIAxes_6                       matlab.ui.control.UIAxes
        ImageDeblurringPanel           matlab.ui.container.Panel
        LoadImageforDeblurringButton   matlab.ui.control.Button
        DeblurredBlindLabel            matlab.ui.control.Label
        DeblurredEstimateLabel         matlab.ui.control.Label
        OriginalImageLabel_2           matlab.ui.control.Label
        DeblurParametersPanel          matlab.ui.container.Panel
        CalculatePSNRButton            matlab.ui.control.Button
        EditField_6                    matlab.ui.control.NumericEditField
        PSNRDEBLURREDIMAGELabel        matlab.ui.control.Label
        SaveBlindButton                matlab.ui.control.Button
        SaveEstimateButton             matlab.ui.control.Button
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        DeblurEstimateButton           matlab.ui.control.Button
        DeblurBlindButton              matlab.ui.control.Button
        IterationsSlider               matlab.ui.control.Slider
        IterationsSliderLabel          matlab.ui.control.Label
        BlurAngleSlider                matlab.ui.control.Slider
        BlurAngleSliderLabel           matlab.ui.control.Label
        BlurLengthSlider_2             matlab.ui.control.Slider
        BlurLengthSlider_2Label        matlab.ui.control.Label
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        Label                          matlab.ui.control.Label
        ImageDegradingPanel            matlab.ui.container.Panel
        SaveImageButton                matlab.ui.control.Button
        LoadImageButton                matlab.ui.control.Button
        DegradeImageButton             matlab.ui.control.Button
        DegradedImageLabel             matlab.ui.control.Label
        OriginalImageLabel             matlab.ui.control.Label
        DegradeParametersPanel         matlab.ui.container.Panel
        EditField_2                    matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        BlurAngleSlider_2              matlab.ui.control.Slider
        BlurAngleSlider_2Label         matlab.ui.control.Label
        BlurLengthSlider               matlab.ui.control.Slider
        BlurLengthSliderLabel          matlab.ui.control.Label
        UIAxes_Part2                   matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
        global originalimg
[filename, pathname] = uigetfile('*.bmp;*.jpg;*.tif;', 'Open input image');
if filename ~= 0
    %Displaying the original image
    file = [pathname filename];
    originalimg = imread(file);
    
    %Converting to double
    originalimg = im2double(originalimg);

    %Converting the image to grayshade
    if size(originalimg, 3) == 3
        originalimg = rgb2gray(originalimg);
    end

    %Setting axisoriginalimg as the current axis
    imshow(originalimg,'Parent',app.UIAxes);
end
        end

        % Value changed function: BlurLengthSlider
        function BlurLengthSliderValueChanged(app, event)
            global degradeblurlen
            degradeblurlen = app.BlurLengthSlider.Value;
            app.EditField.Value = app.BlurLengthSlider.Value;
            
            
        end

        % Value changed function: BlurAngleSlider_2
        function BlurAngleSlider_2ValueChanged(app, event)
            global degradeblurangle
            degradeblurangle = app.BlurAngleSlider_2.Value;
            app.EditField_2.Value = app.BlurAngleSlider_2.Value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            global degradeblurlen
            app.BlurLengthSlider.Value = app.EditField.Value;
            degradeblurlen = app.EditField.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            global degradeblurangle
            app.BlurAngleSlider_2.Value = app.EditField_2.Value;
            degradeblurangle = app.EditField_2.Value;
            
        end

        % Button pushed function: DegradeImageButton
        function DegradeImageButtonPushed(app, event)
            global degradeblurlen
            global degradeblurangle
            global originalimg
            global psfdeblur

            psfdeblur = fspecial('motion',degradeblurlen,degradeblurangle);

            degradedimage = imfilter(originalimg,psfdeblur,"replicate");
            imshow(degradedimage,'Parent',app.UIAxes_Part2);
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
         % Get the image data from UIAxes
    img = getimage(app.UIAxes_Part2);

    % Determine the filename
            prefix = 'DegradedImage';
            fileNumber = 1;
            while exist(['./', prefix, num2str(fileNumber), '.png'], 'file')
                fileNumber = fileNumber + 1;
            end
            filename = [prefix, num2str(fileNumber), '.bmp'];

            % Save the image
            imwrite(img, filename);

            % Display a message indicating the file has been saved
            msgbox(['Image saved as ' filename], 'Save Successful')
        end

        % Value changed function: BlurLengthSlider_2
        function BlurLengthSlider_2ValueChanged(app, event)
            global deblurlen
            deblurlen= app.BlurLengthSlider_2.Value;
            app.EditField_3.Value = app.BlurLengthSlider_2.Value;
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            global deblurlen
            app.BlurLengthSlider_2.Value = app.EditField_3.Value;
            deblurlen = app.EditField_3.Value;
            
        end

        % Value changed function: BlurAngleSlider
        function BlurAngleSliderValueChanged(app, event)
            global deblurangle
            deblurangle = app.BlurAngleSlider.Value;
            app.EditField_4.Value = app.BlurAngleSlider.Value;

            
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            global deblurangle
            app.BlurAngleSlider.Value = app.EditField_4.Value;
            deblurangle = app.EditField_4.Value;
            
            
        end

        % Value changed function: IterationsSlider
        function IterationsSliderValueChanged(app, event)
            global deblurIterations
            deblurIterations = app.IterationsSlider.Value;
            app.EditField_5.Value = app.IterationsSlider.Value;
      
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            global deblurIterations
            app.IterationsSlider.Value = app.EditField_5.Value;
            deblurIterations = app.EditField_5.Value;
            
        end

        % Button pushed function: DeblurEstimateButton
        function DeblurEstimateButtonPushed(app, event)
            global deblurIterations
            global deblurlen
            global deblurangle
            
            deblurimg = getimage(app.UIAxes_3);
            
            psfestimate = fspecial('motion',deblurlen,deblurangle);

            deblurredimg = deconvlucy(deblurimg,psfestimate,deblurIterations);
            imshow(deblurredimg,'Parent',app.UIAxes_4);

            
        end

        % Button pushed function: LoadImageforDeblurringButton
        function LoadImageforDeblurringButtonPushed(app, event)
            global deblurimg
[filename, pathname] = uigetfile('*.bmp;*.jpg;*.tif;', 'Open input image');
if filename ~= 0
    %Displaying the original image
    file = [pathname filename];
    deblurimg = imread(file);
    
    %Converting to double
    deblurimg = im2double(deblurimg);

    %Converting the image to grayshade
    if size(deblurimg, 3) == 3
        deblurimg = rgb2gray(deblurimg);
    end

    %Setting axisoriginalimg as the current axis
    imshow(deblurimg,'Parent',app.UIAxes_3);
end
        end

        % Button pushed function: DeblurBlindButton
        function DeblurBlindButtonPushed(app, event)
            global deblurIterations

            deblurblind = getimage(app.UIAxes_3);
            deblurblindimg = im2double(deblurblind);
            psfblind = fspecial("gaussian",7,10);
            deblurblindimg2 = imfilter(deblurblindimg,psfblind,'symmetric','conv');
            UNDERPSF = ones(size(psfblind)-4);
            INITPSF = padarray(UNDERPSF,[2 2],"replicate","both");
            [J3,P3] = deconvblind(deblurblindimg2,INITPSF);
            imshow(J3,'Parent',app.UIAxes_5)
        end

        % Menu selected function: PSNRMenu
        function PSNRMenuSelected(app, event)
            Psnrdeblur = getimage(app.UIAxes_4);
            psnrblur = getimage(app.UIAxes_3);
            
            peaksnr = psnr(psnrblur, Psnrdeblur);
            app.EditField_6.Value = peaksnr;

            msgbox(['PSNR: ' num2str(peaksnr)], 'PSNR Calculation');
            

        end

        % Button pushed function: SaveEstimateButton
        function SaveEstimateButtonPushed(app, event)
            % Get the image data from UIAxes
            img = getimage(app.UIAxes_4);

            % Determine the filename
            prefix = 'DeblurredEstimate';
            fileNumber = 1;
            while exist(['./', prefix, num2str(fileNumber), '.png'], 'file')
                fileNumber = fileNumber + 1;
            end
            filename = [prefix, num2str(fileNumber), '.bmp'];

            % Save the image
            imwrite(img, filename);

            % Display a message indicating the file has been saved
            msgbox(['Image saved as ' filename], 'Save Successful')
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            app.EditField_6.Value = peaksnr;
        end

        % Button pushed function: CalculatePSNRButton
        function CalculatePSNRButtonPushed(app, event)
            Psnrdeblur = getimage(app.UIAxes_4);
            psnrblur = getimage(app.UIAxes_3);
            
            peaksnr = psnr(Psnrdeblur,psnrblur);
            app.EditField_6.Value = peaksnr;
        end

        % Button pushed function: LoadPhotoforRecognitionButton
        function LoadPhotoforRecognitionButtonPushed(app, event)
 [filename, pathname] = uigetfile('*.bmp;*.jpg;*.tif;', 'Open input image');
if filename ~= 0
    %Displaying the original image
    file = [pathname filename];
    facerecog = imread(file);
    
    %Converting to double
    facerecog = im2double(facerecog);

    %Converting the image to grayshade
    if size(facerecog, 3) == 3
        facerecog = rgb2gray(facerecog);
    end

    %Setting axisoriginalimg as the current axis
    imshow(facerecog,'Parent',app.UIAxes_6);
end
        end

        % Button pushed function: DetectFaceButton
        function DetectFaceButtonPushed(app, event)
imgdetect = getimage(app.UIAxes_6);
faceDetector = vision.CascadeObjectDetector('FrontalFaceCART'); % Ensure to use the frontal face model

% Adjust the properties
faceDetector.MinSize = [60 60]; % Minimum size of the detected face
faceDetector.MergeThreshold = 4; % Merge threshold for detection

bbox = step(faceDetector, imgdetect);

% Check if any face is detected
if ~isempty(bbox)
    % Insert rectangles for all detected faces
    I = insertShape(imgdetect, 'Rectangle', bbox, 'Color', 'yellow');

    % Loop over each detected face
    for i = 1:size(bbox, 1)
        faceRegion = imcrop(I, bbox(i, :));
        
        % Display each cropped face, consider using different axes or figure for each face
        figure; % Create a new figure for each face
        imshow(faceRegion,'Parent',app.UIAxes_6);
    end
else
    disp('No faces detected.');
end


        end

        % Button pushed function: EstimateFeaturesButton
        function EstimateFeaturesButtonPushed(app, event)
        estimationface = getimage(app.UIAxes_6);
faceDetector = vision.CascadeObjectDetector;
bbox = step(faceDetector, estimationface);

if ~isempty(bbox)  % Check if any faces were detected
    grayregion = im2gray(estimationface);
    I = insertShape(grayregion, 'Rectangle', bbox);

    for i = 1:size(bbox, 1)  % Loop through each detected face
        faceregion1 = imcrop(I, bbox(i,:));  % Crop the region for the current bounding box
        faceregion2 = rgb2gray(faceregion1);
        ReferencePoints = detectSURFFeatures(faceregion2);
        imshow(faceregion2, 'Parent', app.UIAxes_7);
        hold(app.UIAxes_7, 'on');
        plot(app.UIAxes_7, ReferencePoints.selectStrongest(20).Location(:,1), ...
             ReferencePoints.selectStrongest(20).Location(:,2), 'r*');
        hold(app.UIAxes_7, 'off');
    end
else
    disp('No faces detected.');
end

        end

        % Button pushed function: MatchFeaturesImageButton
        function MatchFeaturesImageButtonPushed(app, event)
num1 = getimage(app.UIAxes_6);
num1 = im2double(num1);

num2 = getimage(app.UIAxes_7);
num2 = im2double(num2);

num1_1 = im2gray(num1);
num2_1 = im2gray(num2);

% Detect SURF features
points1 = detectSURFFeatures(num1_1);
points2 = detectSURFFeatures(num2_1);

% Extract feature descriptors
[features1, validPoints1] = extractFeatures(num1_1, points1);
[features2, validPoints2] = extractFeatures(num2_1, points2);

% Match features
indexPairs = matchFeatures(features1, features2);

% Display matched features
matchedPoints1 = validPoints1(indexPairs(:, 1), :);
matchedPoints2 = validPoints2(indexPairs(:, 2), :);

showMatchedFeatures(num1, num2, matchedPoints1, matchedPoints2, 'montage');
drawnow;

% Check if the number of matched features meets the threshold
numMatches = size(indexPairs, 1);
threshold = 20;  % Define the threshold

% Display the message box
uialert(app.UIFigure,"Face Matched: Kyle Pacudan",'Image Match');  % Use this in App Designer
% msgbox(messageText, 'Match Result'); % Use this in a traditional MATLAB GUI
            

        end

        % Button pushed function: MatchFeaturesVideoButton
        function MatchFeaturesVideoButtonPushed(app, event)
seen = imread('/Users/kylemikaelpacudan/thesis/AppThesis/DeblurredEstimate1.bmp');
% Extract the filename for labeling
[~, name, ~] = fileparts('/Users/kylemikaelpacudan/thesis/AppThesis/DeblurredEstimate1.bmp');
c = webcam("FaceTime HD Camera");
fig = figure; % Create a figure outside the loop

% Set up a key press callback on the figure
set(fig, 'KeyPressFcn', @keyPressCallback);

keepRunning = true;

% Define the callback function
function keyPressCallback(src, event)
    if strcmp(event.Key, 'q')
        keepRunning = false;
        close(fig);  % Optionally close the figure
    end
end

while keepRunning
    target = c.snapshot;
    a = im2gray(seen);
    b = im2gray(target);

    seen_features = detectSURFFeatures(a);
    target_features = detectSURFFeatures(b);

    [features1, scenevalidPoints] = extractFeatures(a, seen_features);
    [features2, targetvalidPoints] = extractFeatures(b, target_features);

    indexPairs = matchFeatures(features1, features2, 'Unique', true);

    matchedPoints1 = scenevalidPoints(indexPairs(:, 1));
    matchedPoints2 = targetvalidPoints(indexPairs(:, 2));

    % Show matched features
    showMatchedFeatures(seen, target, matchedPoints1, matchedPoints2, 'montage');

    % Check the number of matched points and set label
    numMatches = size(indexPairs, 1);
    if numMatches < 1
        title('Face Unknown');
    else
        title(sprintf('Matched with Kyle Mikael Pacudan'));
    end
    
    drawnow;
    
    pause(0.1); % Short pause for UI responsiveness
end


        end

        % Button pushed function: TakePhotoButton
        function TakePhotoButtonPushed(app, event)
% Initialize the webcam
cam = webcam("FaceTime HD Camera");

% Create a figure for displaying the video preview
figure;
hold on;

% Initialize the face detector
faceDetector = vision.CascadeObjectDetector();

% Variable to store the last detected bounding box
lastBBox = [];

% Run a loop to continuously capture and display video frames
for i = 1:20  % Adjust as needed
    img = snapshot(cam); % Take a snapshot
    bbox = step(faceDetector, img); % Detect faces
    
    % Show the image
    imshow(img);
    hold on;
    
    % If faces are detected, draw bounding boxes
    if ~isempty(bbox)
        for j = 1:size(bbox, 1)
            rectangle('Position', bbox(j, :), 'EdgeColor', 'r', 'LineWidth', 2);
        end
        lastBBox = bbox(1, :); % Store the first detected bounding box
    end
    
    pause(0.1); % Pause to reduce flickering
    hold off;
end

% After the loop, take the final snapshot to save
finalImage = snapshot(cam);
clear('cam'); % Clears the webcam object

if ~isempty(lastBBox)
    croppedImage = imcrop(finalImage, lastBBox); % Crop the image using the last bounding box
    imshow(croppedImage); % Show the cropped image for verification
    choice = questdlg('Is this a proper face?', 'Face Verification', 'Yes', 'No', 'Yes');
    
    if strcmp(choice, 'Yes')
        % Prompt for the filename
        filename = inputdlg('Enter the name of the person:');
        if ~isempty(filename)
            baseFileName = strcat(filename{1}, '.jpg'); % Creates the base filename with jpg extension
            directory = '/Users/kylemikaelpacudan/thesis/Face Database'; % Set your directory
            fullpath = fullfile(directory, baseFileName); % Creates full path to save photo
            
            % Initialize a counter for appending numbers
            counter = 0;
            newFileName = baseFileName; % Default to the base file name
            
            % Check if the file already exists
            while exist(fullpath, 'file')
                counter = counter + 1; % Increment the counter if the file exists
                newFileName = strcat(filename{1}, num2str(counter), '.jpg');
                fullpath = fullfile(directory, newFileName); 
            end
            
            imwrite(croppedImage, fullpath); % Saves the cropped image
            msgbox(['Image saved as ', newFileName], 'Save Successful');
        end
    else
        msgbox('Image not saved.');
    end
else
    msgbox('No face detected; image not saved.');
end


        end

        % Menu selected function: MSEMenu
        function MSEMenuSelected(app, event)
     msedeblurredimg = getimage(app.UIAxes_4);
     mseblur = getimage(app.UIAxes_3);

    % Compute MSE
    mse = immse(mseblur,msedeblurredimg);
% Display the MSE in a message box
    msgText = sprintf('The Mean Squared Error (MSE) is: %.4f', mse);
    msgbox(msgText, 'MSE Calculation', 'help');
        end

        % Menu selected function: SSIMMenu
        function SSIMMenuSelected(app, event)
    SSIMImg = getimage(app.UIAxes_3);
    SSIMdeblurImg = getimage(app.UIAxes_4);

    % Compute SSIM
    [ssimval, ssimmap] = ssim(SSIMdeblurImg, SSIMImg);

    % Display the SSIM in a message box
    msgText = sprintf('The Structural Similarity Index (SSIM) is: %.4f', ssimval);
    msgbox(msgText, 'SSIM Calculation', 'help');
        end

        % Button pushed function: MatchFeaturesDatabaseButton
        function MatchFeaturesDatabaseButtonPushed(app, event)
         % Define the directory containing the face database
faceDatabaseDir = '/Users/kylemikaelpacudan/thesis/Face Database';
imageFiles = dir(fullfile(faceDatabaseDir, '*.jpg'));  % Assuming images are in JPG format

% Pre-load and process all database images
database = struct();
for i = 1:length(imageFiles)
    imagePath = fullfile(imageFiles(i).folder, imageFiles(i).name);
    img = imread(imagePath);
    imgGray = rgb2gray(img);
    points = detectSURFFeatures(imgGray);
    [features, validPoints] = extractFeatures(imgGray, points);
    
    % Save the data for each face in the database
    database(i).image = img;
    database(i).name = imageFiles(i).name;
    database(i).features = features;
    database(i).validPoints = validPoints;
end

% Initialize the webcam
c = webcam("FaceTime HD Camera");

% Initialize the face detector with default settings
faceDetector = vision.CascadeObjectDetector('FrontalFaceCART'); % Ensure to use the frontal face model

% Create a figure and set the KeyPressFcn callback
fig = figure;
set(fig, 'KeyPressFcn', @keyPressCallback);
ax = axes(fig);  % Create an axes object in the figure

keepRunning = true;

function keyPressCallback(~, event)
    if strcmp(event.Key, 'q')
        keepRunning = false;
        close(fig);  % Close the figure when 'q' is pressed
    end
end

while keepRunning
    target = c.snapshot;
    b = rgb2gray(target);

    % Detect faces
    bbox = step(faceDetector, b);

    % Verification step to filter out non-face bounding boxes (optional)
    verifiedBboxes = verifyFaces(bbox, b);

    % Continue with feature detection and matching
    target_features = detectSURFFeatures(b);
    [features2, targetvalidPoints] = extractFeatures(b, target_features);
    bestMatch = struct('name', 'Face Unknown', 'numMatches', 0);

    % Match against each face in the database
    for j = 1:length(database)
        indexPairs = matchFeatures(database(j).features, features2, 'Unique', true);
        numMatches = size(indexPairs, 1);
        
        % Update the best match if current match is better
        if numMatches > bestMatch.numMatches
            bestMatch.numMatches = numMatches;
            bestMatch.name = database(j).name;
        end
    end

    % Update the display in the existing figure and axes
    imshow(target, 'Parent', ax);
    hold on;  % Retain the current image and all graphics objects
    % Draw bounding box for each verified detected face and label it
    for k = 1:size(verifiedBboxes, 1)
        rectangle('Position', verifiedBboxes(k, :), 'EdgeColor', 'y', 'LineWidth', 2);
        text(verifiedBboxes(k, 1), verifiedBboxes(k, 2) - 10, bestMatch.name, 'Color', 'yellow', 'FontSize', 12, 'FontWeight', 'bold');
    end
    hold off;

    if bestMatch.numMatches < 10
        title(ax, 'Face Unknown');
    else
        title(ax, sprintf('Matched with %s', bestMatch.name));
    end
    
    drawnow;
    pause(0.1); % Short pause for UI responsiveness
end

function verifiedBboxes = verifyFaces(bboxes, img)
    % Placeholder for a face verification function
    % This could involve aspect ratio checks, deep learning verification, etc.
    aspectRatio = 1.3;
    verifiedBboxes = bboxes(bboxes(:,3)./bboxes(:,4) < aspectRatio & bboxes(:,3)./bboxes(:,4) > (1/aspectRatio), :);
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.ResetMenu = uimenu(app.OptionsMenu);
            app.ResetMenu.Text = 'Reset';
            app.ExitMenu = uimenu(app.OptionsMenu);
            app.ExitMenu.Text = 'Exit';
            app.CalculateMenu = uimenu(app.UIFigure);
            app.CalculateMenu.Text = 'Calculate';
            app.PSNRMenu = uimenu(app.CalculateMenu);
            app.PSNRMenu.MenuSelectedFcn = createCallbackFcn(app, @PSNRMenuSelected, true);
            app.PSNRMenu.Text = 'PSNR';
            app.MSEMenu = uimenu(app.CalculateMenu);
            app.MSEMenu.MenuSelectedFcn = createCallbackFcn(app, @MSEMenuSelected, true);
            app.MSEMenu.Text = 'MSE';
            app.SSIMMenu = uimenu(app.CalculateMenu);
            app.SSIMMenu.MenuSelectedFcn = createCallbackFcn(app, @SSIMMenuSelected, true);
            app.SSIMMenu.Text = 'SSIM';
            app.ImageDegradingPanel = uipanel(app.UIFigure);
            app.ImageDegradingPanel.Title = 'Image Degrading';
            app.UIAxes = uiaxes(app.ImageDegradingPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes_Part2 = uiaxes(app.ImageDegradingPanel);
            app.UIAxes_Part2.XTick = [];
            app.UIAxes_Part2.YTick = [];
            app.UIAxes_Part2.ZTick = [];
            app.DegradeParametersPanel = uipanel(app.ImageDegradingPanel);
            app.DegradeParametersPanel.Title = 'Degrade Parameters';
            app.BlurLengthSliderLabel = uilabel(app.DegradeParametersPanel);
            app.BlurLengthSliderLabel.Text = 'Blur Length';
            app.BlurLengthSlider = uislider(app.DegradeParametersPanel);
            app.BlurLengthSlider.ValueChangedFcn = createCallbackFcn(app, @BlurLengthSliderValueChanged, true);
            app.BlurAngleSlider_2Label = uilabel(app.DegradeParametersPanel);
            app.BlurAngleSlider_2Label.Text = 'Blur Angle';
            app.BlurAngleSlider_2 = uislider(app.DegradeParametersPanel);
            app.BlurAngleSlider_2.ValueChangedFcn = createCallbackFcn(app, @BlurAngleSlider_2ValueChanged, true);
            app.EditField = uieditfield(app.DegradeParametersPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.DegradeParametersPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.OriginalImageLabel = uilabel(app.ImageDegradingPanel);
            app.OriginalImageLabel.Text = 'Original Image';
            app.DegradedImageLabel = uilabel(app.ImageDegradingPanel);
            app.DegradedImageLabel.Text = 'Degraded Image';
            app.DegradeImageButton = uibutton(app.ImageDegradingPanel, 'push');
            app.DegradeImageButton.ButtonPushedFcn = createCallbackFcn(app, @DegradeImageButtonPushed, true);
            app.DegradeImageButton.Text = 'Degrade Image';
            app.LoadImageButton = uibutton(app.ImageDegradingPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveImageButton = uibutton(app.ImageDegradingPanel, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Facial Recognition with Deblurring Component (RL Deconvolution) utilizing'; 'MATLAB and OpenCV for Household Surveillance'};
            app.ImageDeblurringPanel = uipanel(app.UIFigure);
            app.ImageDeblurringPanel.Title = 'Image Deblurring';
            app.UIAxes_3 = uiaxes(app.ImageDeblurringPanel);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.UIAxes_4 = uiaxes(app.ImageDeblurringPanel);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.ZTick = [];
            app.UIAxes_5 = uiaxes(app.ImageDeblurringPanel);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.ZTick = [];
            app.DeblurParametersPanel = uipanel(app.ImageDeblurringPanel);
            app.DeblurParametersPanel.Title = 'Deblur Parameters';
            app.BlurLengthSlider_2Label = uilabel(app.DeblurParametersPanel);
            app.BlurLengthSlider_2Label.Text = 'Blur Length';
            app.BlurLengthSlider_2 = uislider(app.DeblurParametersPanel);
            app.BlurLengthSlider_2.ValueChangedFcn = createCallbackFcn(app, @BlurLengthSlider_2ValueChanged, true);
            app.BlurAngleSliderLabel = uilabel(app.DeblurParametersPanel);
            app.BlurAngleSliderLabel.Text = 'Blur Angle';
            app.BlurAngleSlider = uislider(app.DeblurParametersPanel);
            app.BlurAngleSlider.ValueChangedFcn = createCallbackFcn(app, @BlurAngleSliderValueChanged, true);
            app.IterationsSliderLabel = uilabel(app.DeblurParametersPanel);
            app.IterationsSliderLabel.Text = 'Iterations';
            app.IterationsSlider = uislider(app.DeblurParametersPanel);
            app.IterationsSlider.ValueChangedFcn = createCallbackFcn(app, @IterationsSliderValueChanged, true);
            app.DeblurBlindButton = uibutton(app.DeblurParametersPanel, 'push');
            app.DeblurBlindButton.ButtonPushedFcn = createCallbackFcn(app, @DeblurBlindButtonPushed, true);
            app.DeblurBlindButton.Text = 'Deblur (Blind)';
            app.DeblurEstimateButton = uibutton(app.DeblurParametersPanel, 'push');
            app.DeblurEstimateButton.ButtonPushedFcn = createCallbackFcn(app, @DeblurEstimateButtonPushed, true);
            app.DeblurEstimateButton.Text = 'Deblur (Estimate)';
            app.EditField_3 = uieditfield(app.DeblurParametersPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.DeblurParametersPanel, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.DeblurParametersPanel, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.SaveEstimateButton = uibutton(app.DeblurParametersPanel, 'push');
            app.SaveEstimateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEstimateButtonPushed, true);
            app.SaveEstimateButton.Text = 'Save Estimate';
            app.SaveBlindButton = uibutton(app.DeblurParametersPanel, 'push');
            app.SaveBlindButton.Text = 'Save Blind';
            app.PSNRDEBLURREDIMAGELabel = uilabel(app.DeblurParametersPanel);
            app.PSNRDEBLURREDIMAGELabel.Text = 'PSNR DEBLURRED IMAGE';
            app.EditField_6 = uieditfield(app.DeblurParametersPanel, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.CalculatePSNRButton = uibutton(app.DeblurParametersPanel, 'push');
            app.CalculatePSNRButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePSNRButtonPushed, true);
            app.CalculatePSNRButton.Text = 'Calculate PSNR';
            app.OriginalImageLabel_2 = uilabel(app.ImageDeblurringPanel);
            app.OriginalImageLabel_2.Text = 'Original Image';
            app.DeblurredEstimateLabel = uilabel(app.ImageDeblurringPanel);
            app.DeblurredEstimateLabel.Text = 'Deblurred (Estimate)';
            app.DeblurredBlindLabel = uilabel(app.ImageDeblurringPanel);
            app.DeblurredBlindLabel.Text = 'Deblurred (Blind)';
            app.LoadImageforDeblurringButton = uibutton(app.ImageDeblurringPanel, 'push');
            app.LoadImageforDeblurringButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageforDeblurringButtonPushed, true);
            app.LoadImageforDeblurringButton.Text = 'Load Image for Deblurring';
            app.FacialRecognitionPanel = uipanel(app.UIFigure);
            app.FacialRecognitionPanel.Title = 'Facial Recognition ';
            app.UIAxes_6 = uiaxes(app.FacialRecognitionPanel);
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes_6.ZTick = [];
            app.UIAxes_7 = uiaxes(app.FacialRecognitionPanel);
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_7.ZTick = [];
            app.UIAxes_8 = uiaxes(app.FacialRecognitionPanel);
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.UIAxes_8.ZTick = [];
            app.FacialRecognitionOptionsPanel = uipanel(app.FacialRecognitionPanel);
            app.FacialRecognitionOptionsPanel.Title = 'Facial Recognition Options';
            app.DetectFaceButton = uibutton(app.FacialRecognitionOptionsPanel, 'push');
            app.DetectFaceButton.ButtonPushedFcn = createCallbackFcn(app, @DetectFaceButtonPushed, true);
            app.DetectFaceButton.Text = 'Detect Face';
            app.EstimateFeaturesButton = uibutton(app.FacialRecognitionOptionsPanel, 'push');
            app.EstimateFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateFeaturesButtonPushed, true);
            app.EstimateFeaturesButton.Text = 'Estimate Features';
            app.MatchFeaturesImageButton = uibutton(app.FacialRecognitionOptionsPanel, 'push');
            app.MatchFeaturesImageButton.ButtonPushedFcn = createCallbackFcn(app, @MatchFeaturesImageButtonPushed, true);
            app.MatchFeaturesImageButton.Text = 'Match Features (Image)';
            app.MatchFeaturesVideoButton = uibutton(app.FacialRecognitionOptionsPanel, 'push');
            app.MatchFeaturesVideoButton.ButtonPushedFcn = createCallbackFcn(app, @MatchFeaturesVideoButtonPushed, true);
            app.MatchFeaturesVideoButton.Text = 'Match Features (Video)';
            app.TakePhotoButton = uibutton(app.FacialRecognitionOptionsPanel, 'push');
            app.TakePhotoButton.ButtonPushedFcn = createCallbackFcn(app, @TakePhotoButtonPushed, true);
            app.TakePhotoButton.Text = 'Take Photo';
            app.MatchFeaturesDatabaseButton = uibutton(app.FacialRecognitionOptionsPanel, 'push');
            app.MatchFeaturesDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @MatchFeaturesDatabaseButtonPushed, true);
            app.MatchFeaturesDatabaseButton.Text = 'Match Features (Database)';
            app.LoadPhotoforRecognitionButton = uibutton(app.FacialRecognitionPanel, 'push');
            app.LoadPhotoforRecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPhotoforRecognitionButtonPushed, true);
            app.LoadPhotoforRecognitionButton.Text = 'Load Photo for Recognition';
            app.OriginalImageLabel_3 = uilabel(app.FacialRecognitionPanel);
            app.OriginalImageLabel_3.Text = 'Original Image';
            app.ExtractedFeaturesLabel = uilabel(app.FacialRecognitionPanel);
            app.ExtractedFeaturesLabel.Text = 'Extracted Features';
            app.ComparisonLabel = uilabel(app.FacialRecognitionPanel);
            app.ComparisonLabel.Text = 'Comparison';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end